classdef samplingBenchmarkInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        VisualizeSamplingCheckBox     matlab.ui.control.CheckBox
        VisualizeButton               matlab.ui.control.Button
        numofclustersEditField        matlab.ui.control.EditField
        ofclustersEditFieldLabel      matlab.ui.control.Label
        SampleintervalEditField       matlab.ui.control.EditField
        SampleintervalEditFieldLabel  matlab.ui.control.Label
        PlanFileTextArea              matlab.ui.control.TextArea
        PlanFileLabel                 matlab.ui.control.Label
        BrowseButton                  matlab.ui.control.Button
        OutputTextArea                matlab.ui.control.TextArea
        OutputLabel                   matlab.ui.control.Label
        voxelsEditField               matlab.ui.control.EditField
        voxelsEditFieldLabel          matlab.ui.control.Label
        layersEditField               matlab.ui.control.EditField
        layersEditFieldLabel          matlab.ui.control.Label
        RunButton                     matlab.ui.control.Button
        FMOConstraintsLabel           matlab.ui.control.Label
        BasicdoseCheckBox             matlab.ui.control.CheckBox
        CVaRCheckBox                  matlab.ui.control.CheckBox
        UpperboundsCheckBox           matlab.ui.control.CheckBox
        UniformityCheckBox_2          matlab.ui.control.CheckBox
        FMOObjectivesLabel            matlab.ui.control.Label
        MinhealthyCheckBox            matlab.ui.control.CheckBox
        MintargetCheckBox             matlab.ui.control.CheckBox
        UniformityCheckBox            matlab.ui.control.CheckBox
        SamplingTypeDropDown          matlab.ui.control.DropDown
        SamplingTypeDropDownLabel     matlab.ui.control.Label
        PlanSelectionPanel            matlab.ui.container.Panel
        PlaninfoLabel                 matlab.ui.control.Label
    end

    properties (Access = private)
        file % Description
        inputData  % Property to store input data
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
    
            constants = [app.BasicdoseCheckBox.Value, app.CVaRCheckBox.Value, app.UpperboundsCheckBox.Value, app.UniformityCheckBox_2.Value];
            objectives = [app.MinhealthyCheckBox.Value, app.MintargetCheckBox.Value, app.UniformityCheckBox.Value];
            layers = app.layersEditField.Value;
            voxelsField = app.voxelsEditField.Value;
            samplingType = app.SamplingTypeDropDown.Value;
            sampleinterval = app.SampleintervalEditField.Value;
            clusters = app.numofclustersEditField.Value;
            visSamp = app.VisualizeSamplingCheckBox.Value;

            % Don't send things through if nothing is selected
            if sum(constants)==0 || sum(objectives)==0
                app.OutputTextArea.Value = 'Choose a set of objectives and constraints!';
                return
            end
            
            % Yell at users if no data has been uploaded!
            if isempty(app.inputData)
                app.OutputTextArea.Value = 'There''s nothing to run, upload some data!';
                return
            end

            app.OutputTextArea.Value = ['Running', char(10)];

            % Set original inputs
            num_beamlets = size(app.inputData.Dij,2);
            target_dose = app.inputData.targetDose;
            target_voxels = app.inputData.structVoxels.target;
            num_target_voxels = numel(app.inputData.structVoxels.target);
            OAR_voxels = app.inputData.structVoxels.OAR;
            num_OAR_voxels = numel(app.inputData.structVoxels.OAR);
            d_target = app.inputData.Dij(app.inputData.structVoxels.target(:), :);
            d_OAR = app.inputData.Dij(app.inputData.structVoxels.OAR(:), :);
            
            % If we're running the full FMO, there's no reason for any sampling 
            if (str2double(sampleinterval)==1 || str2num(clusters) == num_target_voxels || str2num(voxelsField) ==100)
                [status, objVal, runtime, intensityVals] = run_FMO(objectives, d_target, d_OAR, num_target_voxels, num_OAR_voxels, num_beamlets, target_dose);
            else % Otherwise, we need to see what type of sampling we're doing            
                switch samplingType
                    case "Downsample"
                        [d_target, num_target_voxels, target_voxels, sampling_runtime] = integerdownsample(d_target, app.inputData.structVoxels.target, str2double(sampleinterval));
                        if (visSamp); plot_voxels(app.inputData.voxelIndices.target(target_voxels,:)); end
                    case "k-Means (coord)"
                        [d_target, d_OAR, num_target_voxels, num_OAR_voxels, num_beamlets, target_dose, sampling_runtime] = kmeansclustering(app.file, str2num(clusters), str2double(sampleinterval));
                    case "Manham K Clustering"
                        [d_target, d_OAR, num_target_voxels, num_OAR_voxels, num_beamlets, target_dose, sampling_runtime] = kmeansclusteringmanham(app.file, str2num(clusters), str2double(sampleinterval));
                    case "Fountain Clustering"
                        [d_target, d_OAR, num_target_voxels, num_OAR_voxels, num_beamlets, target_dose, sampling_runtime] = fountainclustering(app.file, str2double(sampleinterval));
                    case "k-Means (Dij)"
                    case "k-Nearest Neighbour"
                    case "Beamlet-Based (max)"
                    case "Beamlet-Based (med)"
                    case "Layered" 
                    case "All"
                end
            end
            
%             [status, objVal, runtime, intensityVals] = run_FMO(objectives, d_target, d_OAR, num_target_voxels, num_OAR_voxels, num_beamlets, target_dose);
%             %import run_FMO matrix output txt file = d
%             [avg_deviation, max_overage, voxels_underdosed] = metrics(app.file, d);
%             app.OutputTextArea.Value = ["Average deviation = "+num2str(avg_deviation), newline, "Max overage = "+num2str(max_overage), newline, "Voxels underdosed = "+num2str(voxels_underdosed)];
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            %update file selection text field with file path chosen
            %call uigetfile here
            [app.file, path] = uigetfile('*.mat');
            app.file = fullfile(path,app.file);
            app.PlanFileTextArea.Value = app.file;
            % Load the input as guidata
            % I wanted to do this directly with input = load(... this might
            % be a 2022a-specific bug. Check versions
            tempInput = load(app.file);
            % Get the field names of the temporary structure
            fieldNames = fieldnames(tempInput);
            input = tempInput.(fieldNames{1});
            app.inputData = input;
            app.PlaninfoLabel.Text = strcat(['Plan info: ', num2str(size(input.Dij,1)), ' Voxels; ', num2str(numel(input.structVoxels.target)), ' target, ', num2str(numel(input.structVoxels.OAR)), ' OAR. ',num2str(size(input.Dij,2)), ' Beamlets.']);
        end

        % Button pushed function: VisualizeButton
        function VisualizeButtonPushed(app, event)
            visualizeStructures(app.inputData.voxelIndices);
        end

        % Value changed function: SamplingTypeDropDown
        function SamplingTypeChange(app, event)
            samplingType = app.SamplingTypeDropDown.Value;
            switch samplingType
                case "Layered"
                    app.SampleintervalEditField.Editable='on';
                    app.numofclustersEditField.Editable = 'on';
                    app.voxelsEditField.Editable = 'on';
                    app.layersEditField.Editable='on';    
                case "All"
                    app.SampleintervalEditField.Editable='on';
                    app.numofclustersEditField.Editable = 'on';
                    app.voxelsEditField.Editable = 'on';
                    app.layersEditField.Editable='on';
                otherwise
                    app.SampleintervalEditField.Editable='on';
                    app.numofclustersEditField.Editable = 'on';
                    app.voxelsEditField.Editable = 'on';
                    app.layersEditField.Editable='off';
             end
        end

        % Value changed function: SampleintervalEditField, 
        % ...and 2 other components
        function UpdateValuesSamp(app, event)
            if ~isempty(app.inputData)
                num_target_voxels = numel(app.inputData.structVoxels.target);
                nToAll = 0;
                kToAll=0;
                pToAll=0;
                if event.Source==app.SampleintervalEditField % If sample field
                    value = app.SampleintervalEditField.Value;
                    numericValue = str2double(value);
                    nToAll = 1;
                elseif event.Source==app.voxelsEditField % If sample field
                    value = app.voxelsEditField.Value;
                    numericValue = str2double(value);
                    pToAll = 1;
                elseif event.Source==app.numofclustersEditField % If sample field
                    value = app.numofclustersEditField.Value;
                    numericValue = str2double(value);
                    kToAll = 1;
                end
                if isnan(numericValue)
                    app.TextField.Value = '';  % Reset the text field
                    app.OutputTextArea.Value = 'Please enter a legal value';
                else
                    if ((~pToAll && numericValue<1) || (numericValue<0)  || (pToAll && numericValue>100) || numericValue> num_target_voxels)
                        app.OutputTextArea.Value = 'Sampling rate either too high or too low';
                        app.OutputTextArea.Value = 'Please enter a legal value';
                    else    
                        [n,k,p] = sampleConversion(nToAll, kToAll, pToAll, numericValue, num_target_voxels);
                        app.SampleintervalEditField.Value = num2str(n);
                        app.voxelsEditField.Value = num2str(p);
                        app.numofclustersEditField.Value = num2str(k);
                        app.OutputTextArea.Value = '';
                    end
                end
            else
                app.SampleintervalEditField.Value='';
                app.OutputTextArea.Value = 'Please upload data first';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PlanSelectionPanel = uipanel(app.UIFigure);
            app.PlanSelectionPanel.Title = 'Plan Selection';
            app.PlaninfoLabel = uilabel(app.PlanSelectionPanel);
            app.PlaninfoLabel.Text = 'Plan info:';
            app.SamplingTypeDropDownLabel = uilabel(app.UIFigure);
            app.SamplingTypeDropDownLabel.Text = 'Sampling Type';
            app.SamplingTypeDropDown = uidropdown(app.UIFigure);
            app.SamplingTypeDropDown.Items = {'Downsample', 'k-Means (coord)', 'k-Means (Dij)', 'k-Nearest Neighbour', 'Beamlet-Based (max)', 'Beamlet-Based (med)', 'Layered', 'All'};
            app.SamplingTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SamplingTypeChange, true);
            app.SamplingTypeDropDown.Value = 'Downsample';
            app.UniformityCheckBox = uicheckbox(app.UIFigure);
            app.UniformityCheckBox.Enable = 'off';
            app.UniformityCheckBox.Text = 'Uniformity';
            app.MintargetCheckBox = uicheckbox(app.UIFigure);
            app.MintargetCheckBox.Text = 'Min. target';
            app.MinhealthyCheckBox = uicheckbox(app.UIFigure);
            app.MinhealthyCheckBox.Text = 'Min. healthy';
            app.MinhealthyCheckBox.Value = true;
            app.FMOObjectivesLabel = uilabel(app.UIFigure);
            app.FMOObjectivesLabel.Text = 'FMO Objectives';
            app.UniformityCheckBox_2 = uicheckbox(app.UIFigure);
            app.UniformityCheckBox_2.Enable = 'off';
            app.UniformityCheckBox_2.Text = 'Uniformity';
            app.UpperboundsCheckBox = uicheckbox(app.UIFigure);
            app.UpperboundsCheckBox.Enable = 'off';
            app.UpperboundsCheckBox.Text = 'Upper bounds';
            app.CVaRCheckBox = uicheckbox(app.UIFigure);
            app.CVaRCheckBox.Enable = 'off';
            app.CVaRCheckBox.Text = 'CVaR';
            app.BasicdoseCheckBox = uicheckbox(app.UIFigure);
            app.BasicdoseCheckBox.Text = 'Basic dose';
            app.BasicdoseCheckBox.Value = true;
            app.FMOConstraintsLabel = uilabel(app.UIFigure);
            app.FMOConstraintsLabel.Text = 'FMO Constraints';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.layersEditFieldLabel = uilabel(app.UIFigure);
            app.layersEditFieldLabel.Text = '# layers';
            app.layersEditField = uieditfield(app.UIFigure, 'text');
            app.layersEditField.Editable = 'off';
            app.voxelsEditFieldLabel = uilabel(app.UIFigure);
            app.voxelsEditFieldLabel.Text = '% voxels';
            app.voxelsEditField = uieditfield(app.UIFigure, 'text');
            app.voxelsEditField.ValueChangedFcn = createCallbackFcn(app, @UpdateValuesSamp, true);
            app.OutputLabel = uilabel(app.UIFigure);
            app.OutputLabel.Text = 'Output:';
            app.OutputTextArea = uitextarea(app.UIFigure);
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.PlanFileLabel = uilabel(app.UIFigure);
            app.PlanFileLabel.Text = 'Plan File:';
            app.PlanFileTextArea = uitextarea(app.UIFigure);
            app.SampleintervalEditFieldLabel = uilabel(app.UIFigure);
            app.SampleintervalEditFieldLabel.Text = 'Sample interval';
            app.SampleintervalEditField = uieditfield(app.UIFigure, 'text');
            app.SampleintervalEditField.ValueChangedFcn = createCallbackFcn(app, @UpdateValuesSamp, true);
            app.ofclustersEditFieldLabel = uilabel(app.UIFigure);
            app.ofclustersEditFieldLabel.Text = '# of clusters';
            app.numofclustersEditField = uieditfield(app.UIFigure, 'text');
            app.numofclustersEditField.ValueChangedFcn = createCallbackFcn(app, @UpdateValuesSamp, true);
            app.VisualizeButton = uibutton(app.UIFigure, 'push');
            app.VisualizeButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.VisualizeButton.Text = 'Visualize';
            app.VisualizeSamplingCheckBox = uicheckbox(app.UIFigure);
            app.VisualizeSamplingCheckBox.Text = 'Visualize Sampling';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = samplingBenchmarkInterface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end